classdef Bearphys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Bearphys_0_1                  matlab.ui.Figure
        LoadSweepsetButton            matlab.ui.control.Button
        SweepSliderLabel              matlab.ui.control.Label
        SweepSlider                   matlab.ui.control.Slider
        Lamp                          matlab.ui.control.Lamp
        SubstractBaselineSwitchLabel  matlab.ui.control.Label
        SubstractBaselineSwitch       matlab.ui.control.Switch
        ShowAverageSwitchLabel        matlab.ui.control.Label
        ShowAverageSwitch             matlab.ui.control.Switch
        ShowAllSweepsSwitchLabel      matlab.ui.control.Label
        ShowAllSweepsSwitch           matlab.ui.control.Switch
        BaselineMethodListBoxLabel    matlab.ui.control.Label
        BaselineMethodListBox         matlab.ui.control.ListBox
        AmplitudeButton               matlab.ui.control.Button
        FrequencyButton               matlab.ui.control.Button
        Bearphys01Label               matlab.ui.control.Label
        PrintSelectionButton          matlab.ui.control.Button
        ExportDataButton              matlab.ui.control.Button
        CombineButton                 matlab.ui.control.Button
        RefocusButton                 matlab.ui.control.Button
        SetBaselineButton             matlab.ui.control.Button
        AddExperimentButton           matlab.ui.control.Button
        ExperimentExplorerPanel       matlab.ui.container.Panel
        ExperimentNameLabel           matlab.ui.control.Label
        CellsListBoxLabel             matlab.ui.control.Label
        CellsListBox                  matlab.ui.control.ListBox
        LoadCellButton                matlab.ui.control.Button
        LoadExperimentButton          matlab.ui.control.Button
    end


    properties (Access = public)
        loaded_sweepsets     % Struct with handles to all open sweepsets and listeners
        np_sweepsets = 0;    % Number of loaded sweepsets
        preferences          % User-depended settings, loaded on startup
        loaded_cells         % Struct with path to all loaded cells using experiment explorer
    end

    properties (Access = private)
        currently_selected   % ID of the currently selected sweepset
        sweepset_index = 0;  % Indexing of all openened sweespsets
        app_ID=rand(1);      % This is to find out which app instance made which sweepset
        handles              % Struct with handles to objects
    end

    methods (Access = private)
        
        % Controls visibility of buttons based on if a sweepset is loaded.
        function control_visibility(app)
            if app.np_sweepsets>0
                app.SweepSlider.Enable='on';
                app.Lamp.Enable='on';
                app.SubstractBaselineSwitch.Enable='on';
                app.ShowAverageSwitch.Enable='on';
                app.ShowAllSweepsSwitch.Enable='on';
                app.BaselineMethodListBox.Enable='on';
                app.AmplitudeButton.Enable='on';
                app.FrequencyButton.Enable='on';
                app.PrintSelectionButton.Enable='on';
                app.ExportDataButton.Enable='on';
                app.CombineButton.Enable='on';
                app.RefocusButton.Enable='on';
                app.SetBaselineButton.Enable='on';
            else
                app.SweepSlider.Enable='off';
                app.Lamp.Enable='off';
                app.SubstractBaselineSwitch.Enable='off';
                app.ShowAverageSwitch.Enable='off';
                app.ShowAllSweepsSwitch.Enable='off';
                app.BaselineMethodListBox.Enable='off';
                app.AmplitudeButton.Enable='off';
                app.FrequencyButton.Enable='off';
                app.PrintSelectionButton.Enable='off';
                app.ExportDataButton.Enable='off';
                app.CombineButton.Enable='off';
                app.RefocusButton.Enable='off';
                app.SetBaselineButton.Enable='off';
            end
            
            % Figure out what is probably the active sweepset
            current_sweepset=getappdata(gcf,'object');
            test_type=whos('current_sweepset');
            
            if strcmp(test_type.class,'sweepset')
                % the active figure is a sweepset, check if made with this app instance
                sweepset_app_ID=getappdata(current_sweepset.handles.figure,'app_ID');
                if sweepset_app_ID==app.app_ID % made by this app instance
                    app.currently_selected=getappdata(current_sweepset.handles.figure,'index');
                else
                    % not made by this app instance, but let's include it.
                    app.sweeset_index=app.sweeset_index+1;
                    app.np_sweepsets=app.np_sweepsets+1;
                    setappdata(current_sweepset.handles.figure,'index',app.sweepset_index)
                    setappdata(current_sweepset.handles.figure,'app_ID',app.app_ID);
                    app.currently_selected=getappdata(current_sweepset.handles.figure,'index');
                end      
            end 
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;

            % Populating GUI data
            app.SweepSlider.Limits=[1, current_sweepset.number_of_sweeps];
            app.SweepSlider.MinorTicks=[app.SweepSlider.Limits(1):1:app.SweepSlider.Limits(2)];
            app.SweepSlider.MajorTicks=[app.SweepSlider.Limits(1):1:app.SweepSlider.Limits(2)];
            app.SweepSlider.Value=current_sweepset.current_sweep;
           
            if current_sweepset.sweep_selection(current_sweepset.current_sweep)
                app.Lamp.Color=[0 1 0];
            else
                app.Lamp.Color=[1 0 0];
            end
            
            if current_sweepset.settings.baseline_info.substracted
                app.SubstractBaselineSwitch.Value='On';
            else
                app.SubstractBaselineSwitch.Value='Off';
            end
            
            if strcmp(current_sweepset.handles.average_trace.Visible,'on')
                app.ShowAverageSwitch.Value='On';
            else
                app.ShowAverageSwitch.Value='Off';
            end
            
            if strcmp(current_sweepset.handles.all_sweeps(1).Visible,'on')
                app.ShowAllSweepsSwitch.Value='On';
            else
                app.ShowAllSweepsSwitch.Value='Off';
            end   
        end
        
        function update_everything(app, sweepset, ev, sweepset_ID)
            app.control_visibility;
        end
        
        
        function sweepset_closed(app, sweepset, ev, sweepset_ID)
            delete(app.loaded_sweepsets(sweepset_ID).listeners(1));
            app.np_sweepsets=app.np_sweepsets-1;
            app.control_visibility;
            disp('sweepset closed')
        end
        
        function build_directory(app, path)
            %This function compiles a struct with the name and path to all cell-folders
            %in a specific directory. Cell-folders are folders containing information
            %(traces and other files) about one specific cell. It only works if the
            %data is organized with one main folder (directory 'path') which contains
            %all animal folders, which contain all the cell-folders associated with
            %those animals.
            
            all_animals=dir(path);
            disp(['Loading cells from: ' path]);
            
            if length(all_animals)<1
                error('No animals found, check path')
            end
           
            
            for a=4:length(all_animals)
                animals(a-3).name=all_animals(a).name;
                test=[path '/' all_animals(a).name];
                animals(a-3).path=test;
            end
            
            c=1;
            
            for a=1:length(animals)
                animal_cells=dir(animals(a).path);
                for b=4:length(animal_cells)
                    if animal_cells(b).isdir
                        Cells(c).name=animal_cells(b).name;
                        
                        % grabbing the cell number from the folder name
                        Cells(c).number=str2num(animal_cells(b).name(5:length(animal_cells(b).name-4)));
                        
                        Cells(c).path=[animals(a).path '/' animal_cells(b).name];
                        Cells(c).date=animal_cells(b).date;
                        c=c+1;
                    else end
                end
            end
            
            Real_Cells=Cells; % This will go wrong if there are less folders than cells (for instance if someone deletes a cell data folder).
            assignin('base','hoihoi',Real_Cells)
            for a=1:length(Cells)
                Cells(a).number;
                Real_Cells(Cells(a).number)=Cells(a);
            end

            app.loaded_cells=Real_Cells;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Welcome to Bearphys')
            
            if ~ispref('Bearphys','settings')
                % not preferences available, must be first time Beaprhys is run
                setpref('Bearphys','settings','empty')
                disp('new settings set')
            else
                app.preferences=getpref('Bearphys');
                disp('settings loaded')
            end
            
            
        end

        % Button pushed function: AddExperimentButton
        function AddExperimentButtonPushed(app, event)
            new_experiment=uigetdir('MultiSelect','off');
            new_name=char(inputdlg('New Experiment name: '));
            
            % Check which number experiment this is
            if isfield(app.preferences,'experiments')
                np_experiment=length(app.preferences.experiments)+1;
            else
                np_experiment=1;
            end
            
            app.preferences.experiments(np_experiment).path=new_experiment;
            app.preferences.experiments(np_experiment).name=new_name;
            
            % Store the settings for future use
            setpref('Bearphys','experiments',app.preferences.experiments)
            % assignin('base','hoihoi',app.preferences)
            
        end

        % Button pushed function: AmplitudeButton
        function AmplitudeButtonPushed(app, event)
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            current_sweepset.handles.measurement=measure(current_sweepset);
        end

        % Value changed function: BaselineMethodListBox
        function BaselineMethodListBoxValueChanged(app, event)
            value = app.BaselineMethodListBox.Value;
            value(value==' ')='_';
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            
            current_sweepset.settings.baseline_info.method=value;
            
        end

        % Button pushed function: CombineButton
        function CombineButtonPushed(app, event)
            app.handles.combiner=trace_combiner;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            disp('not currently in code')
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            current_sweepset.handles.firing_frequency=firing_frequency(current_sweepset);
        end

        % Button pushed function: LoadCellButton
        function LoadCellButtonPushed(app, event)
            % Will load the selected cell
            cell_index=app.CellsListBox.Value;
            
            cell_found=false;
            for i=1:length(app.loaded_cells)
                if strcmp(cell_index,char(app.loaded_cells(i).name))
                    path_to_load=app.loaded_cells(i).path;
                    cell_found=true;
                end
            end
            
            if cell_found
                cells=sweepset('directory',path_to_load);
            else
                disp('unable to load cell')
                return
            end
            
            np_sweepset_objects=size(cells.data);
            for i=1:np_sweepset_objects(2);
                % cells.data contains the handles to the sweepset objects
                if ~isvalid(cells.data{i})
                    error('Sweepset class returned non-sweepset objects')
                end
                app.np_sweepsets=app.np_sweepsets+1;
                app.sweepset_index=app.sweepset_index+1;
                this_sweepset_ID=app.sweepset_index;
                
                % Storing the sweepset and it's listeners
                app.loaded_sweepsets(app.sweepset_index).sweepset_handle=cells.data{i};
                app.loaded_sweepsets(app.sweepset_index).listeners(1)=addlistener(cells.data{i},'state_change',@(scr, ev)app.update_everything(scr, ev, this_sweepset_ID));
                app.loaded_sweepsets(app.sweepset_index).listeners(2)=addlistener(cells.data{i},'sweepset_closed',@(scr, ev)app.sweepset_closed(scr, ev, this_sweepset_ID));
                app.loaded_sweepsets(app.sweepset_index).listeners(3)=addlistener(cells.data{i},'sweepset_clicked',@(scr, ev)app.update_everything(scr, ev, this_sweepset_ID));
            
                setappdata(cells.data{i}.handles.figure,'index',app.sweepset_index);
                setappdata(cells.data{i}.handles.figure,'app_ID',app.app_ID);
            end
            
            % Setting this sweepset to be the currently selected
            app.currently_selected=app.sweepset_index;
              
            % Making relevant buttons available
            app.control_visibility;
                
            
        end

        % Button pushed function: LoadExperimentButton
        function LoadExperimentButtonPushed(app, event)
            % Will load experiment and populate experiment explorer
            % NOTE: experiments have to organised as described in the manual
            
            % Get the names of all available experiments
            if isfield(app.preferences,'experiments')
                name_list={app.preferences.experiments.name};
                
                
                input_experiment=listdlg('PromptString','Select an experiment:',...
                                             'SelectionMode','single',...
                                             'ListString',name_list);
                disp(['Loading experiment ', app.preferences.experiments(input_experiment).name])
            
            else
                disp('no experiments available')
                return
            end
            
            % Load all cells found in experiment folder
            build_directory(app,app.preferences.experiments(input_experiment).path);
            
            available_cells={app.loaded_cells.name};
            
            app.ExperimentNameLabel.Enable='on';
            app.CellsListBox.Enable='on';
            app.LoadCellButton.Enable='on';
            app.CellsListBox.Items=available_cells;
            
        end

        % Button pushed function: LoadSweepsetButton
        function LoadSweepsetButtonPushed(app, event)
            app.np_sweepsets=app.np_sweepsets+1;
            app.sweepset_index=app.sweepset_index+1;
            sweepset_object=sweepset('user_select');
            if ~isvalid(sweepset_object)
                return
            end
            
            this_sweepset_ID=app.sweepset_index;
            
            % Storing the sweepset and it's listeners
            app.loaded_sweepsets(app.sweepset_index).sweepset_handle=sweepset_object;
            app.loaded_sweepsets(app.sweepset_index).listeners(1)=addlistener(sweepset_object,'state_change',@(scr, ev)app.update_everything(scr, ev, this_sweepset_ID));
            app.loaded_sweepsets(app.sweepset_index).listeners(2)=addlistener(sweepset_object,'sweepset_closed',@(scr, ev)app.sweepset_closed(scr, ev, this_sweepset_ID));
            app.loaded_sweepsets(app.sweepset_index).listeners(3)=addlistener(sweepset_object,'sweepset_clicked',@(scr, ev)app.update_everything(scr, ev, this_sweepset_ID));
            
            % Setting this sweepset to be the currently selected
            app.currently_selected=app.sweepset_index;
            setappdata(sweepset_object.handles.figure,'index',app.sweepset_index);
            setappdata(sweepset_object.handles.figure,'app_ID',app.app_ID);
            
            % Making relevant buttons available
            app.control_visibility;
             
        end

        % Button pushed function: PrintSelectionButton
        function PrintSelectionButtonPushed(app, event)
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            current_sweepset.sweep_selection
        end

        % Button pushed function: RefocusButton
        function RefocusButtonPushed(app, event)
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            
            floor=min(min(current_sweepset.data))-10;
            roof=max(max(current_sweepset.data))+10;
            disp_right=round(length(current_sweepset.data(:,1,1))/current_sweepset.sampling_frequency);
            
            figure(current_sweepset.handles.figure);
            axis([0 disp_right floor roof])
        end

        % Button pushed function: SetBaselineButton
        function SetBaselineButtonPushed(app, event)
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            current_sweepset.show_baseline;
        end

        % Value changed function: ShowAllSweepsSwitch
        function ShowAllSweepsSwitchValueChanged(app, event)
            value = app.ShowAllSweepsSwitch.Value;
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            if strcmp(value,'On')
                for i=1:current_sweepset.number_of_sweeps
                    set(current_sweepset.handles.all_sweeps(i),'Visible','on');
                end
            else
                for i=1:current_sweepset.number_of_sweeps
                    set(current_sweepset.handles.all_sweeps(i),'Visible','off');
                end
            end 
        end

        % Value changed function: ShowAverageSwitch
        function ShowAverageSwitchValueChanged(app, event)
            value = app.ShowAverageSwitch.Value;
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            if strcmp(value,'On')
                set(current_sweepset.handles.average_trace,'Visible','on');
            else
                set(current_sweepset.handles.average_trace,'Visible','off');
            end   
        end

        % Value changed function: SubstractBaselineSwitch
        function SubstractBaselineSwitchValueChanged(app, event)
            value = app.SubstractBaselineSwitch.Value;
            current_sweepset=app.loaded_sweepsets(app.currently_selected).sweepset_handle;
            if strcmp(value,'On')
                current_sweepset.settings.baseline_info.substracted=true;
            else
                current_sweepset.settings.baseline_info.substracted=false;
            end 
        end

        % Value changed function: SweepSlider
        function SweepSliderValueChanged(app, event)
            value = round(app.SweepSlider.Value);
            app.loaded_sweepsets(app.currently_selected).sweepset_handle.move_sweep(value);
        end

        % Close request function: Bearphys_0_1
        function close_req(app, event)
            
            
            % Deleting all listeners
            for i=1:app.sweepset_index
                delete(app.loaded_sweepsets(i).listeners(1))
                delete(app.loaded_sweepsets(i).listeners(2))
                delete(app.loaded_sweepsets(i).listeners(3))
            end
            
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Bearphys_0_1 = uifigure('Visible', 'off');
            app.Bearphys_0_1.Name = 'UI Figure';
            app.Bearphys_0_1.CloseRequestFcn = createCallbackFcn(app, @close_req, true);
            app.LoadSweepsetButton = uibutton(app.Bearphys_0_1, 'push');
            app.LoadSweepsetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSweepsetButtonPushed, true);
            app.LoadSweepsetButton.Text = 'Load Sweepset';
            app.SweepSliderLabel = uilabel(app.Bearphys_0_1);
            app.SweepSliderLabel.Text = 'Sweep:';
            app.SweepSlider = uislider(app.Bearphys_0_1);
            app.SweepSlider.Limits = [1 100];
            app.SweepSlider.ValueChangedFcn = createCallbackFcn(app, @SweepSliderValueChanged, true);
            app.SweepSlider.Enable = 'off';
            app.SweepSlider.Value = 1;
            app.Lamp = uilamp(app.Bearphys_0_1);
            app.Lamp.Enable = 'off';
            app.SubstractBaselineSwitchLabel = uilabel(app.Bearphys_0_1);
            app.SubstractBaselineSwitchLabel.Text = 'Substract Baseline';
            app.SubstractBaselineSwitch = uiswitch(app.Bearphys_0_1, 'slider');
            app.SubstractBaselineSwitch.ValueChangedFcn = createCallbackFcn(app, @SubstractBaselineSwitchValueChanged, true);
            app.SubstractBaselineSwitch.Enable = 'off';
            app.ShowAverageSwitchLabel = uilabel(app.Bearphys_0_1);
            app.ShowAverageSwitchLabel.Text = 'Show Average';
            app.ShowAverageSwitch = uiswitch(app.Bearphys_0_1, 'slider');
            app.ShowAverageSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowAverageSwitchValueChanged, true);
            app.ShowAverageSwitch.Enable = 'off';
            app.ShowAllSweepsSwitchLabel = uilabel(app.Bearphys_0_1);
            app.ShowAllSweepsSwitchLabel.Text = 'Show All Sweeps';
            app.ShowAllSweepsSwitch = uiswitch(app.Bearphys_0_1, 'slider');
            app.ShowAllSweepsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowAllSweepsSwitchValueChanged, true);
            app.ShowAllSweepsSwitch.Enable = 'off';
            app.ShowAllSweepsSwitch.Value = 'On';
            app.BaselineMethodListBoxLabel = uilabel(app.Bearphys_0_1);
            app.BaselineMethodListBoxLabel.Enable = 'off';
            app.BaselineMethodListBoxLabel.Text = {'Baseline'; 'Method:'};
            app.BaselineMethodListBox = uilistbox(app.Bearphys_0_1);
            app.BaselineMethodListBox.Items = {'standard', 'whole trace', 'moving average 1s'};
            app.BaselineMethodListBox.ValueChangedFcn = createCallbackFcn(app, @BaselineMethodListBoxValueChanged, true);
            app.BaselineMethodListBox.Enable = 'off';
            app.BaselineMethodListBox.Value = 'standard';
            app.AmplitudeButton = uibutton(app.Bearphys_0_1, 'push');
            app.AmplitudeButton.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeButtonPushed, true);
            app.AmplitudeButton.Enable = 'off';
            app.AmplitudeButton.Text = 'Amplitude';
            app.FrequencyButton = uibutton(app.Bearphys_0_1, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Enable = 'off';
            app.FrequencyButton.Text = 'Frequency';
            app.Bearphys01Label = uilabel(app.Bearphys_0_1);
            app.Bearphys01Label.Text = 'Bearphys 0.1';
            app.PrintSelectionButton = uibutton(app.Bearphys_0_1, 'push');
            app.PrintSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PrintSelectionButtonPushed, true);
            app.PrintSelectionButton.Enable = 'off';
            app.PrintSelectionButton.Text = 'Print Selection';
            app.ExportDataButton = uibutton(app.Bearphys_0_1, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.CombineButton = uibutton(app.Bearphys_0_1, 'push');
            app.CombineButton.ButtonPushedFcn = createCallbackFcn(app, @CombineButtonPushed, true);
            app.CombineButton.Enable = 'off';
            app.CombineButton.Text = 'Combine';
            app.RefocusButton = uibutton(app.Bearphys_0_1, 'push');
            app.RefocusButton.ButtonPushedFcn = createCallbackFcn(app, @RefocusButtonPushed, true);
            app.RefocusButton.Enable = 'off';
            app.RefocusButton.Text = 'Refocus';
            app.SetBaselineButton = uibutton(app.Bearphys_0_1, 'push');
            app.SetBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @SetBaselineButtonPushed, true);
            app.SetBaselineButton.Enable = 'off';
            app.SetBaselineButton.Text = 'Set Baseline';
            app.AddExperimentButton = uibutton(app.Bearphys_0_1, 'push');
            app.AddExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @AddExperimentButtonPushed, true);
            app.AddExperimentButton.Text = 'Add Experiment';
            app.ExperimentExplorerPanel = uipanel(app.Bearphys_0_1);
            app.ExperimentExplorerPanel.Title = 'Experiment Explorer';
            app.ExperimentNameLabel = uilabel(app.ExperimentExplorerPanel);
            app.ExperimentNameLabel.Enable = 'off';
            app.ExperimentNameLabel.Text = 'Experiment Name';
            app.CellsListBoxLabel = uilabel(app.ExperimentExplorerPanel);
            app.CellsListBoxLabel.Enable = 'off';
            app.CellsListBoxLabel.Text = 'Cells';
            app.CellsListBox = uilistbox(app.ExperimentExplorerPanel);
            app.CellsListBox.Items = {'Cell 1', 'Cell 2', 'Cell 3', 'Cell 4'};
            app.CellsListBox.Enable = 'off';
            app.CellsListBox.Value = 'Cell 1';
            app.LoadCellButton = uibutton(app.ExperimentExplorerPanel, 'push');
            app.LoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCellButtonPushed, true);
            app.LoadCellButton.Enable = 'off';
            app.LoadCellButton.Text = 'Load Cell';
            app.LoadExperimentButton = uibutton(app.Bearphys_0_1, 'push');
            app.LoadExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentButtonPushed, true);
            app.LoadExperimentButton.Text = 'Load Experiment';
            % Show the figure after all components are created
            app.Bearphys_0_1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bearphys

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Bearphys_0_1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Bearphys_0_1)
        end
    end
end